classdef IISView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        LoadDataTab                     matlab.ui.container.Tab
        Panel1                          matlab.ui.container.Panel
        Channel1FilenameEditFieldLabel  matlab.ui.control.Label
        Channel1FilenameEditField_1     matlab.ui.control.EditField
        Channel2FilenameEditFieldLabel  matlab.ui.control.Label
        Channel2FilenameEditField_1     matlab.ui.control.EditField
        BrowseButton_2                  matlab.ui.control.Button
        BrowseButton                    matlab.ui.control.Button
        TitleEditFieldLabel             matlab.ui.control.Label
        TitleEditField_1                matlab.ui.control.EditField
        Panel2                          matlab.ui.container.Panel
        Channel2FilenameEditField_2Label  matlab.ui.control.Label
        Channel2FilenameEditField_2     matlab.ui.control.EditField
        BrowseButton_3                  matlab.ui.control.Button
        BrowseButton_4                  matlab.ui.control.Button
        Channel1FilenameEditField_2Label  matlab.ui.control.Label
        Channel1FilenameEditField_2     matlab.ui.control.EditField
        TitleEditField_2Label           matlab.ui.control.Label
        TitleEditField_2                matlab.ui.control.EditField
        Panel3                          matlab.ui.container.Panel
        Channel1FilenameEditField_3Label  matlab.ui.control.Label
        Channel1FilenameEditField_3     matlab.ui.control.EditField
        Channel2FilenameEditField_3Label  matlab.ui.control.Label
        Channel2FilenameEditField_3     matlab.ui.control.EditField
        BrowseButton_5                  matlab.ui.control.Button
        BrowseButton_6                  matlab.ui.control.Button
        TitleEditField_3Label           matlab.ui.control.Label
        TitleEditField_3                matlab.ui.control.EditField
        Panel4                          matlab.ui.container.Panel
        Channel1FilenameEditField_4Label  matlab.ui.control.Label
        Channel1FilenameEditField_4     matlab.ui.control.EditField
        Channel2FilenameEditField_4Label  matlab.ui.control.Label
        Channel2FilenameEditField_4     matlab.ui.control.EditField
        BrowseButton_7                  matlab.ui.control.Button
        BrowseButton_8                  matlab.ui.control.Button
        TitleEditField_4Label           matlab.ui.control.Label
        TitleEditField_4                matlab.ui.control.EditField
        Panel5                          matlab.ui.container.Panel
        Channel1FilenameEditField_5Label  matlab.ui.control.Label
        Channel1FilenameEditField_5     matlab.ui.control.EditField
        Channel2FilenameEditField_5Label  matlab.ui.control.Label
        Channel2FilenameEditField_5     matlab.ui.control.EditField
        BrowseButton_9                  matlab.ui.control.Button
        BrowseButton_10                 matlab.ui.control.Button
        TitleEditField_5Label           matlab.ui.control.Label
        TitleEditField_5                matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        ConfigfileEditFieldLabel        matlab.ui.control.Label
        ConfigfileEditField             matlab.ui.control.EditField
        BrowseButton_16                 matlab.ui.control.Button
        LoadfromConfigFileButton        matlab.ui.control.Button
        ViewParametersTab               matlab.ui.container.Tab
        GotoPanel                       matlab.ui.container.Panel
        GoButton                        matlab.ui.control.Button
        MinuteMSLabel                   matlab.ui.control.Label
        MinuteMSEditField               matlab.ui.control.EditField
        RangeParametersPanel            matlab.ui.container.Panel
        MinYValueEditFieldLabel         matlab.ui.control.Label
        MinYValueEditField              matlab.ui.control.NumericEditField
        MaxYValueEditFieldLabel         matlab.ui.control.Label
        MaxYValueEditField              matlab.ui.control.NumericEditField
        SegmentdurationsecondsEditFieldLabel  matlab.ui.control.Label
        SegmentdurationsecondsEditField  matlab.ui.control.NumericEditField
        SetButton                       matlab.ui.control.Button
        FilteringPanel                  matlab.ui.container.Panel
        LowpassfilterHzEditFieldLabel   matlab.ui.control.Label
        LowpassfilterHzEditField        matlab.ui.control.NumericEditField
        Ifsetto0therewillbenofilteringLabel  matlab.ui.control.Label
        FilterButton                    matlab.ui.control.Button
        Ifbotharesetto0thelimitswillbeautomaticallysetLabel  matlab.ui.control.Label
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        SamplingRateEditField           matlab.ui.control.NumericEditField
        SetbeforeloadingdataLabel       matlab.ui.control.Label
        AutoSaveEveryEditFieldLabel     matlab.ui.control.Label
        AutoSaveEveryEditField          matlab.ui.control.NumericEditField
        SecondsonlyifautosavecheckboxisselectedLabel  matlab.ui.control.Label
        BadDataSegmentPanel             matlab.ui.container.Panel
        StartPointEditFieldLabel        matlab.ui.control.Label
        StartPointEditField             matlab.ui.control.NumericEditField
        EndPointEditFieldLabel          matlab.ui.control.Label
        EndPointEditField               matlab.ui.control.NumericEditField
        ChooseButton                    matlab.ui.control.Button
        ChooseButton_2                  matlab.ui.control.Button
        AddBadSegmentButton             matlab.ui.control.Button
        PanelNumEditFieldLabel          matlab.ui.control.Label
        PanelNumEditField               matlab.ui.control.NumericEditField
        IISTab                          matlab.ui.container.Tab
        Panel1_2                        matlab.ui.container.Panel
        IISFileEditFieldLabel           matlab.ui.control.Label
        IISFileEditField_1              matlab.ui.control.EditField
        DeleteinCurrentTimeWindowButton_1  matlab.ui.control.Button
        DeletefromEntireChannelButton_1  matlab.ui.control.Button
        LoadButton_1                    matlab.ui.control.Button
        SaveButton_1                    matlab.ui.control.Button
        ShowAutomaticDetectionButton_1  matlab.ui.control.Button
        HideAutomaticDetectionButton_1  matlab.ui.control.Button
        BrowseButton_11                 matlab.ui.control.Button
        AutosaveCheckBox_1              matlab.ui.control.CheckBox
        AcceptAutomaticButton_1         matlab.ui.control.Button
        Panel2_2                        matlab.ui.container.Panel
        IISFileEditField_2Label         matlab.ui.control.Label
        IISFileEditField_2              matlab.ui.control.EditField
        DeleteCurrentTimeWindowButton_2  matlab.ui.control.Button
        DeletefromEntireChannelButton_2  matlab.ui.control.Button
        LoadButton_2                    matlab.ui.control.Button
        SaveButton_2                    matlab.ui.control.Button
        BrowseButton_12                 matlab.ui.control.Button
        ShowAutomaticDetectionButton_2  matlab.ui.control.Button
        HideAutomaticDetectionButton_2  matlab.ui.control.Button
        AutosaveCheckBox_2              matlab.ui.control.CheckBox
        AcceptAutomaticButton_2         matlab.ui.control.Button
        Panel3_2                        matlab.ui.container.Panel
        DeleteCurrentTimeWindowButton_3  matlab.ui.control.Button
        DeletefromEntireChannelButton_3  matlab.ui.control.Button
        IISFileEditField_3Label         matlab.ui.control.Label
        IISFileEditField_3              matlab.ui.control.EditField
        LoadButton_3                    matlab.ui.control.Button
        SaveButton_3                    matlab.ui.control.Button
        BrowseButton_13                 matlab.ui.control.Button
        ShowAutomaticDetectionButton_3  matlab.ui.control.Button
        HideAutomaticDetectionButton_3  matlab.ui.control.Button
        AutosaveCheckBox_3              matlab.ui.control.CheckBox
        AcceptAutomaticButton_3         matlab.ui.control.Button
        Panel4_2                        matlab.ui.container.Panel
        DeleteCurrentTimeWindowButton_4  matlab.ui.control.Button
        DeletefromEntireChannelButton_4  matlab.ui.control.Button
        IISFileEditField_4Label         matlab.ui.control.Label
        IISFileEditField_4              matlab.ui.control.EditField
        LoadButton_4                    matlab.ui.control.Button
        SaveButton_4                    matlab.ui.control.Button
        BrowseButton_14                 matlab.ui.control.Button
        ShowAutomaticDetectionButton_4  matlab.ui.control.Button
        HideAutomaticDetectionButton_4  matlab.ui.control.Button
        AutosaveCheckBox_4              matlab.ui.control.CheckBox
        AcceptAutomaticButton_4         matlab.ui.control.Button
        Panel5_2                        matlab.ui.container.Panel
        DeleteCurrentTimeWindowButton_5  matlab.ui.control.Button
        DeletefromEntireChannelButton_5  matlab.ui.control.Button
        IISFileEditField_5Label         matlab.ui.control.Label
        IISFileEditField_5              matlab.ui.control.EditField
        LoadButton_5                    matlab.ui.control.Button
        SaveButton_5                    matlab.ui.control.Button
        BrowseButton_15                 matlab.ui.control.Button
        ShowAutomaticDetectionButton_5  matlab.ui.control.Button
        HideAutomaticDetectionButton_5  matlab.ui.control.Button
        AutosaveCheckBox_5              matlab.ui.control.CheckBox
        AcceptAutomaticButton_5         matlab.ui.control.Button
        LoadAllButton                   matlab.ui.control.Button
        SaveAllButton                   matlab.ui.control.Button
        DeleteAllinCurrentWindowButton  matlab.ui.control.Button
        DeleteAllButton                 matlab.ui.control.Button
        ShowAutomaticDetectionforAllButton  matlab.ui.control.Button
        HideAutomaticDetectionforAllButton  matlab.ui.control.Button
        AcceptAutomaticDetectionforallButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        dataPanels = {}; % Data to be displayed in the panels
        filteredData = {};
        panelNames = {};
        maxPanels = 5; %maximal number of panels
        nPanels = 0; %number of panels to display
        currMinTime = 1;
        currMaxTime;
        timeRange = 30; %seconds
        samplingRate = 1000;
        minYaxis = nan;
        maxYaxis = nan;
        LPfilterPassband = 70; %Hz
        IISindices = {};
        dataFig = []; % The figure on which data is presented
        isLoaded = false;
        automaticIISindices = {}; % cell array of automatically detected IIS
        showAutomaticDetection = [];
        plotHeight = 120 % width, height
        figureWidth = 1310 % width
        figureLeft = 300;
        figureBottom = 100; % Description
        timers = {}; % Description
        isAutoSave = []; % Description
        autoSavePeriod = 300; % how much time to wait between autosaves, seconds
        minDistSpikes = 200; % ms
        badSegments = {}; % Description
        badSegStartMarkingOn = false; % Description
        badSegEndMarkingOn = false; % Description
    end
    
    methods (Access = private)
        
        function plotPanels(app,panelsRange)
            
            if nargin < 2
                panelsRange = [1:app.nPanels];
            end
            
            for iPanel = panelsRange
                currAx = subplot(app.nPanels,1,iPanel,'parent',app.dataFig);
                xaxis = [app.currMinTime:app.currMaxTime]/app.samplingRate;
                plot(currAx,xaxis, app.filteredData{iPanel}(app.currMinTime:app.currMaxTime));
                
                if ~isnan(app.minYaxis) && ~isnan(app.maxYaxis)
                    ylim(currAx,[app.minYaxis app.maxYaxis]);
                else
                    ylim(currAx,'auto');
                end
                
                title(currAx,app.panelNames{iPanel});
                xlabel(currAx, 'Time (sec)');
                ylabel(currAx, 'Amplitude (microVolt)');
                
                if length(app.IISindices)>=iPanel && ~isempty(app.IISindices{iPanel})
                    currIISindices = app.IISindices{iPanel}(app.IISindices{iPanel}>=app.currMinTime & app.IISindices{iPanel}<=app.currMaxTime);
                    if ~isempty(currIISindices)
                        hold(currAx,'on');
                        plot(currAx,currIISindices/app.samplingRate,app.filteredData{iPanel}(currIISindices),'Marker','o','MarkerSize',14,'Color','k','linestyle','none','LineWidth',2);
                        hold(currAx,'off');
                    end
                end
                
                %automatic detection plotting
                if app.showAutomaticDetection(iPanel) && length(app.automaticIISindices)>=iPanel && ~isempty(app.automaticIISindices{iPanel})
                    currAutoIISindices = app.automaticIISindices{iPanel}(app.automaticIISindices{iPanel}>=app.currMinTime & app.automaticIISindices{iPanel}<=app.currMaxTime);
                    if ~isempty(currAutoIISindices)
                        currMaxY = currAx.YLim(2);
                        hold(currAx,'on');
                        plot(currAx,currAutoIISindices/app.samplingRate,currMaxY,'Marker','*','MarkerSize',16,'Color','g','linestyle','none','LineWidth',2);
                        hold(currAx,'off');
                    end
                end
                
                %bad segments plotting
                if length(app.badSegments)>=iPanel && ~isempty(app.badSegments{iPanel})
                    hold(currAx,'on');
                    for iSeg = 1:size(app.badSegments{iPanel},1)
                        segInCurrPanel = intersect([app.badSegments{iPanel}(iSeg,1):app.badSegments{iPanel}(iSeg,2)],[app.currMinTime:app.currMaxTime]);
                        if ~isempty(segInCurrPanel)
                            plot(currAx,segInCurrPanel/app.samplingRate,app.filteredData{iPanel}(segInCurrPanel),'color','r');
                        end
                    end
                    hold(currAx,'off');
                end
                
            end         
            
            app.setFigureFcns;
            
        end
        
        
        function deleteCurrPanelIIS(app, panelNum)
            if length(app.IISindices)<panelNum || app.nPanels<panelNum
                return;
            end
            
            oldPointsInds = app.IISindices{panelNum} >= app.currMinTime & app.IISindices{panelNum} <= app.currMaxTime;
            app.IISindices{panelNum}(oldPointsInds) = [];
            
            app.plotPanels(panelNum);
        end
        
        function callbackGoToPanel(app)
            a = app.GotoPanel;
            set(a,'KeyPressFcn',@(fig_obj, eventDat) app.pressedGoTo(eventDat));
        end
        
        function pressedGoTo(app,ho,eventData)
            msgbox(['succeeded ' eventData.Key]);
        end


        function setFigureFcns(app)
            
            for iPanel = 1:app.nPanels
                set(app.dataFig.Children(iPanel),'ButtonDownFcn',@(hObject,eventdata)app.pressPanel(hObject,eventdata,iPanel));
            end
            
            set(app.dataFig,'KeyPressFcn',@(hObject,eventdata)app.pressedKeyOnFig(hObject,eventdata));
        end
        
        function pressedKeyOnFig(app,hobj,eventdata)
            switch eventdata.Key
                case 'rightarrow'
                    app.moveForward;
                case 'leftarrow'
                    app.moveBackward;
                case '1'
                    app.deleteCurrPanelIIS(1);
                case '2'
                    app.deleteCurrPanelIIS(2);
                case '3'
                    app.deleteCurrPanelIIS(3);
                case '4'
                    app.deleteCurrPanelIIS(4);
                case '5'
                    app.deleteCurrPanelIIS(5);
            end
        end
        
        function changeIISPanelsNames(app,panelNum)
            if nargin < 2
                panelsRange = 1:app.nPanels;
            else
                panelsRange = panelNum;
            end
            
            for iPanel = panelsRange
                app.(['Panel',num2str(iPanel),'_2']).Title = app.panelNames{iPanel};
            end
        end
        
        function panelName = getDefaultName(app,panelNum)
            fileName1 = app.(['Channel1FilenameEditField_',num2str(panelNum)]).Value;
            fileName2 = app.(['Channel2FilenameEditField_',num2str(panelNum)]).Value;
            
            if ~isempty(fileName1) && ~isempty(fileName2)
                panelName1 = fileName1(find(fileName1=='\',1,'last')+1:end);
                panelName2 = fileName2(find(fileName2=='\',1,'last')+1:end);
                
                if ~isempty(panelName1) && ~isempty(panelName2)
                    panelName = [panelName1, '-', panelName2];
                else
                    panelName = [fileName1, '-', fileName2];
                end
            else
                panelName = ['Panel ',num2str(panelNum)];
            end
        end
        
        function titleChangeFunc(app,panelNum)
            value = app.(['TitleEditField_',num2str(panelNum)]).Value;
            if ~isempty(value)
                app.panelNames{panelNum} = value;
            else
                app.panelNames{panelNum} = app.getDefaultName(panelNum);
            end
            
            if app.isLoaded && app.nPanels >= panelNum
                app.plotPanels(panelNum);
            end
            app.changeIISPanelsNames(panelNum);
        end
        
        function loadIISFile(app,panelNum)
            filename = app.(['IISFileEditField_',num2str(panelNum)]).Value;
            if ~isempty(filename)
                try
                    currIIS = load(filename);
                catch
                    errordlg('Failed loading file');
                    return;
                end
                
                if app.(['AutosaveCheckBox_',num2str(panelNum)]).Value
                    app.startTimer(panelNum);
                end
            else
                errordlg('No file name provided');
                return;
            end
            
            %makes sure this is a column matrix
            if size(currIIS.manualIIS,1) == 1
                currIIS.manualIIS = currIIS.manualIIS';
            end
            app.IISindices{panelNum} = currIIS.manualIIS;
            
            if isfield(currIIS,'automaticIIS')
                if size(currIIS.automaticIIS,1) == 1
                    currIIS.automaticIIS = currIIS.automaticIIS';
                end
                app.automaticIISindices{panelNum} = currIIS.automaticIIS;
            end
            if isfield(currIIS, 'badSegments')
                app.badSegments{panelNum} = currIIS.badSegments;
            end
            
            app.plotPanels(panelNum);
            
            %start timer for saving IIS file
            msgbox('Loaded');
        end
        
        function saveIISFile(app,panelNum)
            filename = app.(['IISFileEditField_',num2str(panelNum)]).Value;
            if ~isempty(filename)
                if length(app.IISindices)>=panelNum
                    manualIIS = app.IISindices{panelNum};
                else
                    manualIIS = [];
                end
                if length(app.automaticIISindices)>=panelNum
                    automaticIIS = app.automaticIISindices{panelNum};
                else
                    automaticIIS = [];
                end
                if length(app.badSegments)>=panelNum
                    badSegments = app.badSegments{panelNum};
                else
                    badSegments = [];
                end
                IISdata = app.getIISData(panelNum);
                save(filename,'manualIIS','automaticIIS','IISdata','badSegments');
            else
                errordlg('No file name provided');
                return;
            end
            msgbox('Saved');
        end
        
        function deleteEntireChannelIIS(app,panelNum)
            if length(app.IISindices)<panelNum || app.nPanels<panelNum
                return;
            end
            
            app.IISindices{panelNum} = [];
            
            app.plotPanels(panelNum);
        end


        function moveForward(app)
            nextMinTime = app.currMinTime + app.timeRange*app.samplingRate;
            if nextMinTime > length(app.filteredData{1})
                msgbox('End of data');
                return;
            end
            
            app.currMinTime = app.currMinTime + app.timeRange*app.samplingRate;
            app.currMaxTime = min(app.currMinTime+app.timeRange*app.samplingRate, length(app.filteredData{1}));
            
            app.plotPanels;
        end
        
        function moveBackward(app)
            app.currMinTime = max(1, app.currMinTime - app.timeRange*app.samplingRate);
            app.currMaxTime = min(app.currMinTime+app.timeRange*app.samplingRate, length(app.filteredData{1}));
            
            app.plotPanels;
        end
        
        function startTimer(app, panelNum, periodChanged)
            if nargin < 3
                periodChanged = false;
            end
            if length(app.timers) < panelNum || (length(app.timers) >= panelNum && isempty(app.timers{panelNum})) || periodChanged
                app.timers{panelNum} = timer('Period', app.autoSavePeriod, 'TimerFcn', @(~,~)app.saveIISFileAutosave(panelNum),'ExecutionMode','fixedSpacing');
            end
            if strcmp(app.timers{panelNum}.Running,'off')
                start(app.timers{panelNum});
            end
        end
        
        function stopTimer(app, panelNum, toDelete)
            if nargin < 3
                toDelete = false;
            end
            
           if length(app.timers) >= panelNum && ~isempty(app.timers{panelNum}) && strcmp(app.timers{panelNum}.Running,'on')
               stop(app.timers{panelNum});
               if toDelete
                   delete(app.timers{panelNum});
               end
           end
           
        end
        
        function IISData = getIISData(app, panelNum)
            fileName1 = app.(['Channel1FilenameEditField_',num2str(panelNum)]).Value;
            fileName2 = app.(['Channel2FilenameEditField_',num2str(panelNum)]).Value;
            IISData.fileNames = {fileName1, fileName2};
            IISData.samplingRate = app.samplingRate;
            IISData.time = datetime;
        end
        
        function acceptAutomaticDetection(app, panelNum)
            if length(app.automaticIISindices) < panelNum || (length(app.automaticIISindices) >= panelNum && isempty(app.automaticIISindices))
                return;
            end
            
            if length(app.IISindices) < panelNum
                app.IISindices{panelNum} = app.automaticIISindices{panelNum};
                return;
            end
            
            app.IISindices{panelNum} = [app.IISindices{panelNum}; app.automaticIISindices{panelNum}];
            app.IISindices{panelNum} = app.mergeAdjacentIIS(app.IISindices{panelNum});
        end
        
        function mergedData = mergeAdjacentIIS(app, IISdata)
            minDist = app.minDistSpikes*app.samplingRate/1000;
            mergedData = [];
            
            if isempty(IISdata)
                return;
            end
            
            IISdata = sort(IISdata);
            iSpike = 1;
            %go through spikes and for each spike merge it with all the spikes in the minDist range ahead of it (choose it as the "representative")
            while iSpike <= length(IISdata)
                mergedData = [mergedData; IISdata(iSpike)];
                spikesInRange = IISdata > IISdata(iSpike) & IISdata <= IISdata(iSpike)+minDist;
                iSpike = iSpike + sum(spikesInRange) + 1;
            end
            
        end
        
        function newPoints = addPoints(app,oldPoints, points)
            %the method adds points to the existing IIS - for any point that is less than minDist from other points, it will not add it and erase the 
            %existing close poinst (i.e. will understand it as an indication to erase points rather than add new ones)
            
            toErase = false(length(oldPoints),1);
            toAdd = [];
            for iPoint = 1:length(points)
                closePoints = abs(oldPoints-points(iPoint)) < app.minDistSpikes;
                if sum(closePoints) > 0
                    toErase = toErase | closePoints;
                else
                    toAdd = [toAdd; points(iPoint)];
                end
            end
            newPoints = oldPoints(~toErase);
            newPoints = [newPoints; toAdd];
        end
        
        function saveIISFileAutosave(app,panelNum)
            filename = app.(['IISFileEditField_',num2str(panelNum)]).Value;
            matInd = findstr(filename,'.mat');
            filename = [filename(1:matInd-1),'_autoSave.mat'];
            if ~isempty(filename)
                if length(app.IISindices)>=panelNum
                    manualIIS = app.IISindices{panelNum};
                else
                    manualIIS = [];
                end
                if length(app.automaticIISindices)>=panelNum
                    automaticIIS = app.automaticIISindices{panelNum};
                else
                    automaticIIS = [];
                end
                if length(app.badSegments)>=panelNum
                    badSegments = app.badSegments{panelNum};
                else
                    badSegments = [];
                end
                IISdata = app.getIISData(panelNum);
                save(filename,'manualIIS','automaticIIS','IISdata','badSegments');
            else
                return;
            end
        end
        
    end
    
    methods (Access = public)
        
        function pressPanel(app,hObject,eventdata,panelNum)
            
            %check if this is a marking of start/end bad segment
            if app.badSegStartMarkingOn || app.badSegEndMarkingOn
                [xi,yi] = getpts(app.dataFig.Children(panelNum));
                if length(xi) > 1
                    errordlg('Choose only one point');
                    app.badSegStartMarkingOn = false;
                    app.badSegEndMarkingOn = false;
                    return;
                end
                if length(xi) < 1
                    errordlg('No point was chosen');
                    app.badSegStartMarkingOn = false;
                    app.badSegEndMarkingOn = false;
                    return;
                end
                if app.badSegStartMarkingOn
                    app.StartPointEditField.Value = xi;
                    app.PanelNumEditField.Value = panelNum;
                end
                
                if app.badSegEndMarkingOn
                    app.EndPointEditField.Value = xi;
                    app.PanelNumEditField.Value = panelNum;
                end
                
                app.badSegStartMarkingOn = false;
                app.badSegEndMarkingOn = false;
                return;
            end
            
            if length(app.IISindices)<panelNum
                app.IISindices{panelNum} = [];
            end
            
            try
                [xi,yi] = getpts(app.dataFig.Children(panelNum));
            catch
                xi = [];
                errordlg('No new points added');
            end
            
            xi = round(xi*app.samplingRate);
            
            app.IISindices{panelNum} = app.addPoints(app.IISindices{panelNum}, xi);
            
            app.plotPanels(panelNum);
            
        end
        
    end
    

    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            [filename, filepath] = uigetfile;
            try
                app.Channel1FilenameEditField_1.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_2
        function BrowseButton_2Pushed(app, event)
            [filename, filepath] = uigetfile;
            try
                app.Channel2FilenameEditField_1.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_4
        function BrowseButton_4Pushed(app, event)
            [filename, filepath] = uigetfile;
            try
                app.Channel1FilenameEditField_2.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_3
        function BrowseButton_3Pushed(app, event)
            [filename, filepath] = uigetfile;
            try
                app.Channel2FilenameEditField_2.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_6
        function BrowseButton_6Pushed(app, event)
            [filename, filepath] = uigetfile;
            try
                app.Channel1FilenameEditField_3.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_5
        function BrowseButton_5Pushed(app, event)
            [filename, filepath] = uigetfile;
            try
                app.Channel2FilenameEditField_3.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_8
        function BrowseButton_8Pushed(app, event)
            [filename, filepath] = uigetfile;
            try
                app.Channel1FilenameEditField_4.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_7
        function BrowseButton_7Pushed(app, event)
            [filename, filepath] = uigetfile;
            try
                app.Channel2FilenameEditField_4.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_10
        function BrowseButton_10Pushed(app, event)
            [filename, filepath] = uigetfile;
            try
                app.Channel1FilenameEditField_5.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_9
        function BrowseButton_9Pushed(app, event)
            [filename, filepath] = uigetfile;
            try
                app.Channel2FilenameEditField_5.Value = [filepath filename];
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            
            isError = false;
            %this is to make sure the property is initially set the same as what's written in the text-box
            if app.AutoSaveEveryEditField.Value > 0
                app.autoSavePeriod = app.AutoSaveEveryEditField.Value;
            end
            
            %Try loading other panels
            for iPanel = 1:app.maxPanels
                
                app.IISindices{iPanel} = [];
                app.automaticIISindices{iPanel} = [];
                app.showAutomaticDetection(iPanel) = false;
                app.isAutoSave(iPanel) = app.(['AutosaveCheckBox_',num2str(iPanel)]).Value;
                app.badSegments{iPanel} = [];
                
                fileName1 = app.(['Channel1FilenameEditField_',num2str(iPanel)]).Value;
                fileName2 = app.(['Channel2FilenameEditField_',num2str(iPanel)]).Value;
                
                if ~isempty(app.(['TitleEditField_',num2str(iPanel)]).Value)
                    app.panelNames{iPanel} = app.(['TitleEditField_',num2str(iPanel)]).Value;
                else
                    app.panelNames{iPanel} = app.getDefaultName(iPanel);
                end
                
                if ~isempty(fileName1) && ~isempty(fileName2)
                    currChan1 = load(fileName1);
                    currChan2 = load(fileName2);
                    try
                        app.dataPanels{iPanel} = currChan1.data-currChan2.data;
                        if ~isnan(app.LPfilterPassband)
                            nanInds = isnan(app.dataPanels{iPanel});
                            app.dataPanels{iPanel}(nanInds) = 0;
                            app.filteredData{iPanel} = lowpass(app.dataPanels{iPanel},app.LPfilterPassband,app.samplingRate);
                            app.dataPanels{iPanel}(nanInds) = nan;
                            app.filteredData{iPanel}(nanInds) = nan;
                        else
                            app.filteredData{iPanel} = app.dataPanels{iPanel};
                        end
                    catch
                        isError = true;
                    end
                    
                    if isempty(app.dataPanels{iPanel})
                        isError = true;
                    end
                else
                    isError = true;
                end
                
                if isError
                    app.nPanels = iPanel-1;
                    break;
                end
                
            end
            
            if ~isError
                app.nPanels = app.maxPanels;
            end
            
            app.currMaxTime = min(app.currMinTime+app.timeRange*app.samplingRate, length(app.filteredData{1}));
            if isempty(app.dataFig)
                app.dataFig = figure('units','pixels');
            else
                try
                    close(app.dataFig);
                end
                app.dataFig = figure('units','pixels');
            end
            
            %set figure location
            currPositionFig = get(app.dataFig,'position');
            currPositionFig(1) = app.figureLeft;
            currPositionFig(2) = app.figureBottom;
            currPositionFig(3) = app.figureWidth;
            currPositionFig(4) = app.plotHeight*app.nPanels*1.5;
            set(app.dataFig, 'Position', currPositionFig);
            
            app.plotPanels;
            app.dataFig.Children = flipud(app.dataFig.Children);
            
            app.setFigureFcns;
            
            app.isLoaded = true;
            msgbox([num2str(app.nPanels), ' panels were loaded']);
            
        end

        % Button pushed function: GoButton
        function GoButtonPushed(app, event)
            
            if app.nPanels == 0
                errordlg('No data loaded');
                return;
            end
            
            isError = false;
            timeValue = app.MinuteMSEditField.Value;
            findSplit = find(timeValue==':');
            if isempty(findSplit)
                isError = true;
            end
            try
                mins = str2num(timeValue(1:findSplit-1));
                secs = str2num(timeValue(findSplit+1:end));
            catch
                isError = true;
            end
            if isError
                errordlg('Wrong time format');
                return;
            end
            app.currMinTime = (mins*60+secs)*app.samplingRate+1;
            if app.currMinTime > length(app.filteredData{1})
                errordlg('Time point is beyond the data''s duration');
                return;
            end
            
            app.currMaxTime = min(app.currMinTime+app.timeRange*app.samplingRate, length(app.filteredData{1}));
            
            app.plotPanels;
        end

        % Button pushed function: SetButton
        function SetButtonPushed(app, event)
            if app.SegmentdurationsecondsEditField.Value > 0
                app.timeRange = app.SegmentdurationsecondsEditField.Value;
            else
                errordlg('Segment duration should be longer than 0');
                return;
            end
            
            if app.MinYValueEditField.Value < app.MaxYValueEditField.Value
                app.minYaxis = app.MinYValueEditField.Value;
                app.maxYaxis = app.MaxYValueEditField.Value;
            else if (app.MinYValueEditField.Value==0 && app.MaxYValueEditField.Value==0)
                    app.minYaxis = nan;
                    app.maxYaxis = nan;
                else
                    errordlg('Min Y value should be smaller than Max Y value');
                    return;
                end
            end
            
            app.currMaxTime = min(app.currMinTime+app.timeRange*app.samplingRate, length(app.filteredData{1}));
            
            app.plotPanels;
        end

        % Button pushed function: FilterButton
        function FilterButtonPushed(app, event)
            if app.LowpassfilterHzEditField.Value > 0
                app.LPfilterPassband = app.LowpassfilterHzEditField.Value;
            else if app.LowpassfilterHzEditField.Value==0
                    app.LPfilterPassband = nan;
                else
                    errordlg('Illegal highpass value, value is not changed');
                    return;
                end
            end
            
            for iPanel = 1:app.nPanels
                if ~isnan(app.LPfilterPassband)
                    nanInds = isnan(app.dataPanels{iPanel});
                    app.dataPanels{iPanel}(nanInds) = 0;
                    app.filteredData{iPanel} = lowpass(app.dataPanels{iPanel},app.LPfilterPassband,app.samplingRate);
                    app.dataPanels{iPanel}(nanInds) = nan;
                    app.filteredData{iPanel}(nanInds) = nan;
                else
                    app.filteredData{iPanel} = app.dataPanels{iPanel};
                end
            end
            
            app.plotPanels;
            
            msgbox('Finished');
            
        end

        % Value changed function: SamplingRateEditField
        function SamplingRateEditFieldValueChanged(app, event)
            value = app.SamplingRateEditField.Value;
            if value > 0
                app.samplingRate = value;
            else
                errordlg('Illegal value');
            end
        end

        % Value changed function: LowpassfilterHzEditField
        function LowpassfilterHzEditFieldValueChanged(app, event)
            value = app.LowpassfilterHzEditField.Value;
            
            if app.LowpassfilterHzEditField.Value > 0
                app.LPfilterPassband = app.LowpassfilterHzEditField.Value;
            else if app.LowpassfilterHzEditField.Value==0
                    app.LPfilterPassband = nan;
                else
                    errordlg('Illegal highpass value, value is not changed');
                    app.LowpassfilterHzEditField.Value = app.LPfilterPassband;
                end
            end
        end

        % Value changed function: TitleEditField_1
        function TitleEditField_1ValueChanged(app, event)
            app.titleChangeFunc(1);
        end

        % Value changed function: TitleEditField_2
        function TitleEditField_2ValueChanged(app, event)
            app.titleChangeFunc(2);
        end

        % Value changed function: TitleEditField_3
        function TitleEditField_3ValueChanged(app, event)
            app.titleChangeFunc(3);
        end

        % Value changed function: TitleEditField_4
        function TitleEditField_4ValueChanged(app, event)
            app.titleChangeFunc(4);
        end

        % Value changed function: TitleEditField_5
        function TitleEditField_5ValueChanged(app, event)
            app.titleChangeFunc(5);
        end

        % Button pushed function: BrowseButton_11
        function BrowseButton_11Pushed2(app, event)
            [filename, filepath] = uigetfile;
            try
                app.IISFileEditField_1.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_12
        function BrowseButton_12Pushed2(app, event)
            [filename, filepath] = uigetfile;
            try
                app.IISFileEditField_2.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_13
        function BrowseButton_13Pushed2(app, event)
            [filename, filepath] = uigetfile;
            try
                app.IISFileEditField_3.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_14
        function BrowseButton_14Pushed2(app, event)
            [filename, filepath] = uigetfile;
            try
                app.IISFileEditField_4.Value = [filepath filename];
            end
        end

        % Button pushed function: BrowseButton_15
        function BrowseButton_15Pushed2(app, event)
            [filename, filepath] = uigetfile;
            try
                app.IISFileEditField_5.Value = [filepath filename];
            end
        end

        % Button pushed function: LoadButton_1
        function LoadButton_1Pushed(app, event)
            app.loadIISFile(1);
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.loadIISFile(2);
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            app.loadIISFile(3);
        end

        % Button pushed function: LoadButton_4
        function LoadButton_4Pushed(app, event)
            app.loadIISFile(4);
        end

        % Button pushed function: LoadButton_5
        function LoadButton_5Pushed(app, event)
            app.loadIISFile(5);
        end

        % Button pushed function: LoadAllButton
        function LoadAllButtonPushed(app, event)
            nLoaded = 0;
            for iPanel = 1:app.nPanels
                if isempty(app.(['IISFileEditField_',num2str(iPanel)]).Value)
                    continue;
                end
                app.loadIISFile(iPanel);
                nLoaded = nLoaded+1;
            end
            msgbox(['Loaded ',num2str(nLoaded),' files']);
        end

        % Button pushed function: SaveButton_1
        function SaveButton_1Pushed(app, event)
            app.saveIISFile(1);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2Pushed(app, event)
            app.saveIISFile(2);
        end

        % Button pushed function: SaveButton_3
        function SaveButton_3Pushed(app, event)
            app.saveIISFile(3);
        end

        % Button pushed function: SaveButton_4
        function SaveButton_4Pushed(app, event)
            app.saveIISFile(4);
        end

        % Button pushed function: SaveButton_5
        function SaveButton_5Pushed(app, event)
            app.saveIISFile(5);
        end

        % Button pushed function: SaveAllButton
        function SaveAllButtonPushed(app, event)
            nSaved = 0;
            for iPanel = 1:app.nPanels
                if isempty(app.(['IISFileEditField_',num2str(iPanel)]).Value)
                    continue;
                end
                app.saveIISFile(iPanel);
                nSaved = nSaved+1;
            end
            msgbox(['Saved ',num2str(nSaved),' files']);
        end

        % Button pushed function: DeleteinCurrentTimeWindowButton_1
        function DeleteinCurrentTimeWindowButton_1Pushed(app, event)
            app.deleteCurrPanelIIS(1);
        end

        % Button pushed function: DeleteCurrentTimeWindowButton_2
        function DeleteCurrentTimeWindowButton_2Pushed(app, event)
            app.deleteCurrPanelIIS(2);
        end

        % Button pushed function: DeleteCurrentTimeWindowButton_3
        function DeleteCurrentTimeWindowButton_3Pushed(app, event)
            app.deleteCurrPanelIIS(3);
        end

        % Button pushed function: DeleteCurrentTimeWindowButton_4
        function DeleteCurrentTimeWindowButton_4Pushed(app, event)
            app.deleteCurrPanelIIS(4);
        end

        % Button pushed function: DeleteCurrentTimeWindowButton_5
        function DeleteCurrentTimeWindowButton_5Pushed(app, event)
            app.deleteCurrPanelIIS(5);
        end

        % Button pushed function: DeleteAllinCurrentWindowButton
        function DeleteAllinCurrentWindowButtonPushed(app, event)
            for iPanel = 1:app.nPanels
                app.deleteCurrPanelIIS(iPanel);
            end
        end

        % Button pushed function: DeletefromEntireChannelButton_1
        function DeletefromEntireChannelButton_1Pushed(app, event)
            app.deleteEntireChannelIIS(1);
        end

        % Button pushed function: DeletefromEntireChannelButton_2
        function DeletefromEntireChannelButton_2Pushed(app, event)
            app.deleteEntireChannelIIS(2);
        end

        % Button pushed function: DeletefromEntireChannelButton_3
        function DeletefromEntireChannelButton_3Pushed(app, event)
            app.deleteEntireChannelIIS(3);
        end

        % Button pushed function: DeletefromEntireChannelButton_4
        function DeletefromEntireChannelButton_4Pushed(app, event)
            app.deleteEntireChannelIIS(4);
        end

        % Button pushed function: DeletefromEntireChannelButton_5
        function DeletefromEntireChannelButton_5Pushed(app, event)
            app.deleteEntireChannelIIS(5);
        end

        % Button pushed function: DeleteAllButton
        function DeleteAllButtonPushed(app, event)
            for iPanel = 1:app.nPanels
                app.deleteEntireChannelIIS(iPanel);
            end
        end

        % Button pushed function: ShowAutomaticDetectionButton_1
        function ShowAutomaticDetectionButton_1Pushed(app, event)
            app.showAutomaticDetection(1) = true;
            app.plotPanels(1);
        end

        % Button pushed function: ShowAutomaticDetectionButton_2
        function ShowAutomaticDetectionButton_2Pushed(app, event)
            app.showAutomaticDetection(2) = true;
            app.plotPanels(2);
        end

        % Button pushed function: ShowAutomaticDetectionButton_3
        function ShowAutomaticDetectionButton_3Pushed(app, event)
            app.showAutomaticDetection(3) = true;
            app.plotPanels(3);
        end

        % Button pushed function: ShowAutomaticDetectionButton_4
        function ShowAutomaticDetectionButton_4Pushed(app, event)
            app.showAutomaticDetection(4) = true;
            app.plotPanels(4);
        end

        % Button pushed function: ShowAutomaticDetectionButton_5
        function ShowAutomaticDetectionButton_5Pushed(app, event)
            app.showAutomaticDetection(5) = true;
            app.plotPanels(5);
        end

        % Button pushed function: HideAutomaticDetectionButton_1
        function HideAutomaticDetectionButton_1Pushed(app, event)
            app.showAutomaticDetection(1) = false;
            app.plotPanels(1);
        end

        % Button pushed function: HideAutomaticDetectionButton_2
        function HideAutomaticDetectionButton_2Pushed(app, event)
            app.showAutomaticDetection(2) = false;
            app.plotPanels(2);
        end

        % Button pushed function: HideAutomaticDetectionButton_3
        function HideAutomaticDetectionButton_3Pushed(app, event)
            app.showAutomaticDetection(3) = false;
            app.plotPanels(3);
        end

        % Button pushed function: HideAutomaticDetectionButton_4
        function HideAutomaticDetectionButton_4Pushed(app, event)
            app.showAutomaticDetection(4) = false;
            app.plotPanels(4);
        end

        % Button pushed function: HideAutomaticDetectionButton_5
        function HideAutomaticDetectionButton_5Pushed(app, event)
            app.showAutomaticDetection(5) = false;
            app.plotPanels(5);
        end

        % Button pushed function: BrowseButton_16
        function BrowseButton_16Pushed2(app, event)
            [filename, filepath] = uigetfile;
            try
                app.ConfigfileEditField.Value = [filepath filename];
            end
        end

        % Button pushed function: LoadfromConfigFileButton
        function LoadfromConfigFileButtonPushed(app, event)
            if ~isempty(app.ConfigfileEditField.Value)
                
                try
                    filesData = load(app.ConfigfileEditField.Value);
                catch
                    errordlg('Failed loading file');
                    return;
                end
                
                try
                    app.isLoaded = false;
                    nFiles = length(filesData.dataFileNames);
                    for iFile = 1:nFiles
                        app.(['Channel1FilenameEditField_',num2str(iFile)]).Value = filesData.dataFileNames{iFile}{1};
                        app.(['Channel2FilenameEditField_',num2str(iFile)]).Value = filesData.dataFileNames{iFile}{2};
                    end
                    for iFile = nFiles+1:app.maxPanels
                        app.(['Channel1FilenameEditField_',num2str(iFile)]).Value = '';
                        app.(['Channel2FilenameEditField_',num2str(iFile)]).Value = '';
                    end
                    
                    nTitles = length(filesData.dataTitles);
                    for iTitle = 1:nTitles
                        app.(['TitleEditField_',num2str(iTitle)]).Value = filesData.dataTitles{iTitle};
                        app.titleChangeFunc(iTitle);
                    end
                    for iTitle = nTitles+1:app.maxPanels
                        app.(['TitleEditField_',num2str(iTitle)]).Value = '';
                    end
                    
                    nIISFiles = length(filesData.IISFileNames);
                    for iIISFile = 1:nIISFiles
                        app.(['IISFileEditField_',num2str(iIISFile)]).Value = filesData.IISFileNames{iIISFile};
                    end
                    for iIISFile = nIISFiles+1:app.maxPanels
                        app.(['IISFileEditField_',num2str(iIISFile)]).Value = '';
                    end
                catch
                    errordlg('Wrong format of config file');
                    return;
                end
                
                app.LoadButtonPushed;
                app.LoadAllButtonPushed;
            else
                errordlg('No file name given');
            end
        end

        % Button pushed function: ShowAutomaticDetectionforAllButton
        function ShowAutomaticDetectionforAllButtonPushed(app, event)
            app.showAutomaticDetection = true(1,app.nPanels);
            app.plotPanels;
        end

        % Button pushed function: HideAutomaticDetectionforAllButton
        function HideAutomaticDetectionforAllButtonPushed(app, event)
            app.showAutomaticDetection = false(1,app.nPanels);
            app.plotPanels;
        end

        % Value changed function: AutosaveCheckBox_1
        function AutosaveCheckBox_1ValueChanged(app, event)
            value = app.AutosaveCheckBox_1.Value;
            app.isAutoSave(1) = value;
            
            if value
                app.startTimer(1);
            else
                app.stopTimer(1);
            end
        end

        % Value changed function: AutosaveCheckBox_2
        function AutosaveCheckBox_2ValueChanged(app, event)
            value = app.AutosaveCheckBox_2.Value;
            app.isAutoSave(2) = value;
            
            if value
                app.startTimer(2);
            else
                app.stopTimer(2);
            end
        end

        % Value changed function: AutosaveCheckBox_3
        function AutosaveCheckBox_3ValueChanged(app, event)
            value = app.AutosaveCheckBox_3.Value;
            app.isAutoSave(3) = value;
            
            if value
                app.startTimer(3);
            else
                app.stopTimer(3);
            end
        end

        % Value changed function: AutosaveCheckBox_4
        function AutosaveCheckBox_4ValueChanged(app, event)
            value = app.AutosaveCheckBox_4.Value;
            app.isAutoSave(4) = value;
            
            if value
                app.startTimer(4);
            else
                app.stopTimer(4);
            end
        end

        % Value changed function: AutosaveCheckBox_5
        function AutosaveCheckBox_5ValueChanged(app, event)
            value = app.AutosaveCheckBox_5.Value;
            app.isAutoSave(5) = value;
            
            if value
                app.startTimer(5);
            else
                app.stopTimer(5);
            end
        end

        % Value changed function: AutoSaveEveryEditField
        function AutoSaveEveryEditFieldValueChanged(app, event)
            value = app.AutoSaveEveryEditField.Value;
            if value > 0
                app.autoSavePeriod = value;
                for iPanel = 1:app.nPanels
                    if isempty(app.(['IISFileEditField_',num2str(iPanel)]).Value)
                        continue;
                    end
                    app.stopTimer(iPanel, true);
                    app.startTimer(iPanel, true);
                end
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            %delete the timers
            for iPanel = 1:length(app.timers)
                app.stopTimer(iPanel,true);
            end
            
            delete(app);
            
        end

        % Button pushed function: AcceptAutomaticButton_1
        function AcceptAutomaticButton_1Pushed(app, event)
            app.acceptAutomaticDetection(1);
            app.plotPanels(1);
        end

        % Button pushed function: AcceptAutomaticButton_2
        function AcceptAutomaticButton_2Pushed(app, event)
            app.acceptAutomaticDetection(2);
            app.plotPanels(2);
        end

        % Button pushed function: AcceptAutomaticButton_3
        function AcceptAutomaticButton_3Pushed(app, event)
            app.acceptAutomaticDetection(3);
            app.plotPanels(3);
        end

        % Button pushed function: AcceptAutomaticButton_4
        function AcceptAutomaticButton_4Pushed(app, event)
            app.acceptAutomaticDetection(4);
            app.plotPanels(4);
        end

        % Button pushed function: AcceptAutomaticButton_5
        function AcceptAutomaticButton_5Pushed(app, event)
            app.acceptAutomaticDetection(5);
            app.plotPanels(5);
        end

        % Button pushed function: AcceptAutomaticDetectionforallButton
        function AcceptAutomaticDetectionforallButtonPushed(app, event)
            for iPanel = 1:app.nPanels
                app.acceptAutomaticDetection(iPanel);
            end
            app.plotPanels;
        end

        % Button pushed function: ChooseButton
        function ChooseButtonPushed(app, event)
            if app.badSegEndMarkingOn
                app.badSegEndMarkingOn = false;
            end
            app.badSegStartMarkingOn = true;
        end

        % Button pushed function: ChooseButton_2
        function ChooseButton_2Pushed(app, event)
            if app.badSegStartMarkingOn
                app.badSegStartMarkingOn = false;
            end
            app.badSegEndMarkingOn = true;
        end

        % Button pushed function: AddBadSegmentButton
        function AddBadSegmentButtonPushed(app, event)
            startSeg = round(app.StartPointEditField.Value * app.samplingRate);
            endSeg = round(app.EndPointEditField.Value * app.samplingRate);
            panelNum = app.PanelNumEditField.Value;
            if startSeg > 0 && endSeg > 0 && startSeg < endSeg && endSeg <= length(app.filteredData{panelNum})
                if length(app.badSegments)<panelNum
                    app.badSegments{panelNum} = [startSeg endSeg];
                else
                    app.badSegments{panelNum} = [app.badSegments{panelNum}; startSeg endSeg];
                end
                app.plotPanels(panelNum);
            else
                errordlg('Illegal values');
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoadDataTab = uitab(app.TabGroup);
            app.LoadDataTab.Title = 'Load Data';
            app.Panel1 = uipanel(app.LoadDataTab);
            app.Panel1.Title = 'Panel #1';
            app.Channel1FilenameEditFieldLabel = uilabel(app.Panel1);
            app.Channel1FilenameEditFieldLabel.Text = 'Channel #1 Filename';
            app.Channel1FilenameEditField_1 = uieditfield(app.Panel1, 'text');
            app.Channel2FilenameEditFieldLabel = uilabel(app.Panel1);
            app.Channel2FilenameEditFieldLabel.Text = 'Channel #2 Filename';
            app.Channel2FilenameEditField_1 = uieditfield(app.Panel1, 'text');
            app.BrowseButton_2 = uibutton(app.Panel1, 'push');
            app.BrowseButton_2.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_2Pushed, true);
            app.BrowseButton_2.Text = 'Browse';
            app.BrowseButton = uibutton(app.Panel1, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.TitleEditFieldLabel = uilabel(app.Panel1);
            app.TitleEditFieldLabel.Text = 'Title';
            app.TitleEditField_1 = uieditfield(app.Panel1, 'text');
            app.TitleEditField_1.ValueChangedFcn = createCallbackFcn(app, @TitleEditField_1ValueChanged, true);
            app.Panel2 = uipanel(app.LoadDataTab);
            app.Panel2.Title = 'Panel #2';
            app.Channel2FilenameEditField_2Label = uilabel(app.Panel2);
            app.Channel2FilenameEditField_2Label.Text = 'Channel #2 Filename';
            app.Channel2FilenameEditField_2 = uieditfield(app.Panel2, 'text');
            app.BrowseButton_3 = uibutton(app.Panel2, 'push');
            app.BrowseButton_3.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_3Pushed, true);
            app.BrowseButton_3.Text = 'Browse';
            app.BrowseButton_4 = uibutton(app.Panel2, 'push');
            app.BrowseButton_4.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_4Pushed, true);
            app.BrowseButton_4.Text = 'Browse';
            app.Channel1FilenameEditField_2Label = uilabel(app.Panel2);
            app.Channel1FilenameEditField_2Label.Text = 'Channel #1 Filename';
            app.Channel1FilenameEditField_2 = uieditfield(app.Panel2, 'text');
            app.TitleEditField_2Label = uilabel(app.Panel2);
            app.TitleEditField_2Label.Text = 'Title';
            app.TitleEditField_2 = uieditfield(app.Panel2, 'text');
            app.TitleEditField_2.ValueChangedFcn = createCallbackFcn(app, @TitleEditField_2ValueChanged, true);
            app.Panel3 = uipanel(app.LoadDataTab);
            app.Panel3.Title = 'Panel #3';
            app.Channel1FilenameEditField_3Label = uilabel(app.Panel3);
            app.Channel1FilenameEditField_3Label.Text = 'Channel #1 Filename';
            app.Channel1FilenameEditField_3 = uieditfield(app.Panel3, 'text');
            app.Channel2FilenameEditField_3Label = uilabel(app.Panel3);
            app.Channel2FilenameEditField_3Label.Text = 'Channel #2 Filename';
            app.Channel2FilenameEditField_3 = uieditfield(app.Panel3, 'text');
            app.BrowseButton_5 = uibutton(app.Panel3, 'push');
            app.BrowseButton_5.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_5Pushed, true);
            app.BrowseButton_5.Text = 'Browse';
            app.BrowseButton_6 = uibutton(app.Panel3, 'push');
            app.BrowseButton_6.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_6Pushed, true);
            app.BrowseButton_6.Text = 'Browse';
            app.TitleEditField_3Label = uilabel(app.Panel3);
            app.TitleEditField_3Label.Text = 'Title';
            app.TitleEditField_3 = uieditfield(app.Panel3, 'text');
            app.TitleEditField_3.ValueChangedFcn = createCallbackFcn(app, @TitleEditField_3ValueChanged, true);
            app.Panel4 = uipanel(app.LoadDataTab);
            app.Panel4.Title = 'Panel #4';
            app.Channel1FilenameEditField_4Label = uilabel(app.Panel4);
            app.Channel1FilenameEditField_4Label.Text = 'Channel #1 Filename';
            app.Channel1FilenameEditField_4 = uieditfield(app.Panel4, 'text');
            app.Channel2FilenameEditField_4Label = uilabel(app.Panel4);
            app.Channel2FilenameEditField_4Label.Text = 'Channel #2 Filename';
            app.Channel2FilenameEditField_4 = uieditfield(app.Panel4, 'text');
            app.BrowseButton_7 = uibutton(app.Panel4, 'push');
            app.BrowseButton_7.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_7Pushed, true);
            app.BrowseButton_7.Text = 'Browse';
            app.BrowseButton_8 = uibutton(app.Panel4, 'push');
            app.BrowseButton_8.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_8Pushed, true);
            app.BrowseButton_8.Text = 'Browse';
            app.TitleEditField_4Label = uilabel(app.Panel4);
            app.TitleEditField_4Label.Text = 'Title';
            app.TitleEditField_4 = uieditfield(app.Panel4, 'text');
            app.TitleEditField_4.ValueChangedFcn = createCallbackFcn(app, @TitleEditField_4ValueChanged, true);
            app.Panel5 = uipanel(app.LoadDataTab);
            app.Panel5.Title = 'Panel #5';
            app.Channel1FilenameEditField_5Label = uilabel(app.Panel5);
            app.Channel1FilenameEditField_5Label.Text = 'Channel #1 Filename';
            app.Channel1FilenameEditField_5 = uieditfield(app.Panel5, 'text');
            app.Channel2FilenameEditField_5Label = uilabel(app.Panel5);
            app.Channel2FilenameEditField_5Label.Text = 'Channel #2 Filename';
            app.Channel2FilenameEditField_5 = uieditfield(app.Panel5, 'text');
            app.BrowseButton_9 = uibutton(app.Panel5, 'push');
            app.BrowseButton_9.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_9Pushed, true);
            app.BrowseButton_9.Text = 'Browse';
            app.BrowseButton_10 = uibutton(app.Panel5, 'push');
            app.BrowseButton_10.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_10Pushed, true);
            app.BrowseButton_10.Text = 'Browse';
            app.TitleEditField_5Label = uilabel(app.Panel5);
            app.TitleEditField_5Label.Text = 'Title';
            app.TitleEditField_5 = uieditfield(app.Panel5, 'text');
            app.TitleEditField_5.ValueChangedFcn = createCallbackFcn(app, @TitleEditField_5ValueChanged, true);
            app.LoadButton = uibutton(app.LoadDataTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ConfigfileEditFieldLabel = uilabel(app.LoadDataTab);
            app.ConfigfileEditFieldLabel.Text = 'Config file';
            app.ConfigfileEditField = uieditfield(app.LoadDataTab, 'text');
            app.BrowseButton_16 = uibutton(app.LoadDataTab, 'push');
            app.BrowseButton_16.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_16Pushed2, true);
            app.BrowseButton_16.Text = 'Browse';
            app.LoadfromConfigFileButton = uibutton(app.LoadDataTab, 'push');
            app.LoadfromConfigFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromConfigFileButtonPushed, true);
            app.LoadfromConfigFileButton.Text = 'Load from Config File';
            app.ViewParametersTab = uitab(app.TabGroup);
            app.ViewParametersTab.Title = 'View Parameters';
            app.GotoPanel = uipanel(app.ViewParametersTab);
            app.GotoPanel.Title = 'Go to';
            app.GoButton = uibutton(app.GotoPanel, 'push');
            app.GoButton.ButtonPushedFcn = createCallbackFcn(app, @GoButtonPushed, true);
            app.GoButton.Text = 'Go';
            app.MinuteMSLabel = uilabel(app.GotoPanel);
            app.MinuteMSLabel.Text = 'Minute (M:S)';
            app.MinuteMSEditField = uieditfield(app.GotoPanel, 'text');
            app.MinuteMSEditField.Value = '0:0';
            app.RangeParametersPanel = uipanel(app.ViewParametersTab);
            app.RangeParametersPanel.Title = 'Range Parameters';
            app.MinYValueEditFieldLabel = uilabel(app.RangeParametersPanel);
            app.MinYValueEditFieldLabel.Text = 'Min Y Value';
            app.MinYValueEditField = uieditfield(app.RangeParametersPanel, 'numeric');
            app.MaxYValueEditFieldLabel = uilabel(app.RangeParametersPanel);
            app.MaxYValueEditFieldLabel.Text = 'Max Y Value';
            app.MaxYValueEditField = uieditfield(app.RangeParametersPanel, 'numeric');
            app.SegmentdurationsecondsEditFieldLabel = uilabel(app.RangeParametersPanel);
            app.SegmentdurationsecondsEditFieldLabel.Text = 'Segment duration (seconds)';
            app.SegmentdurationsecondsEditField = uieditfield(app.RangeParametersPanel, 'numeric');
            app.SegmentdurationsecondsEditField.Value = 30;
            app.SetButton = uibutton(app.RangeParametersPanel, 'push');
            app.SetButton.ButtonPushedFcn = createCallbackFcn(app, @SetButtonPushed, true);
            app.SetButton.Text = 'Set';
            app.FilteringPanel = uipanel(app.ViewParametersTab);
            app.FilteringPanel.Title = 'Filtering';
            app.LowpassfilterHzEditFieldLabel = uilabel(app.FilteringPanel);
            app.LowpassfilterHzEditFieldLabel.Text = 'Low pass filter (Hz)';
            app.LowpassfilterHzEditField = uieditfield(app.FilteringPanel, 'numeric');
            app.LowpassfilterHzEditField.ValueChangedFcn = createCallbackFcn(app, @LowpassfilterHzEditFieldValueChanged, true);
            app.LowpassfilterHzEditField.Value = 70;
            app.Ifsetto0therewillbenofilteringLabel = uilabel(app.FilteringPanel);
            app.Ifsetto0therewillbenofilteringLabel.Text = 'If set to 0 there will be no filtering';
            app.FilterButton = uibutton(app.FilteringPanel, 'push');
            app.FilterButton.ButtonPushedFcn = createCallbackFcn(app, @FilterButtonPushed, true);
            app.FilterButton.Text = 'Filter';
            app.Ifbotharesetto0thelimitswillbeautomaticallysetLabel = uilabel(app.ViewParametersTab);
            app.Ifbotharesetto0thelimitswillbeautomaticallysetLabel.Text = 'If both are set to 0 the limits will be automatically set';
            app.SamplingRateEditFieldLabel = uilabel(app.ViewParametersTab);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.ViewParametersTab, 'numeric');
            app.SamplingRateEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingRateEditFieldValueChanged, true);
            app.SamplingRateEditField.Value = 1000;
            app.SetbeforeloadingdataLabel = uilabel(app.ViewParametersTab);
            app.SetbeforeloadingdataLabel.Text = 'Set before loading data';
            app.AutoSaveEveryEditFieldLabel = uilabel(app.ViewParametersTab);
            app.AutoSaveEveryEditFieldLabel.Text = 'Auto Save Every';
            app.AutoSaveEveryEditField = uieditfield(app.ViewParametersTab, 'numeric');
            app.AutoSaveEveryEditField.ValueChangedFcn = createCallbackFcn(app, @AutoSaveEveryEditFieldValueChanged, true);
            app.AutoSaveEveryEditField.Value = 300;
            app.SecondsonlyifautosavecheckboxisselectedLabel = uilabel(app.ViewParametersTab);
            app.SecondsonlyifautosavecheckboxisselectedLabel.Text = 'Seconds (only if auto-save checkbox is selected)';
            app.BadDataSegmentPanel = uipanel(app.ViewParametersTab);
            app.BadDataSegmentPanel.Title = 'Bad Data Segment';
            app.StartPointEditFieldLabel = uilabel(app.BadDataSegmentPanel);
            app.StartPointEditFieldLabel.Text = 'Start Point';
            app.StartPointEditField = uieditfield(app.BadDataSegmentPanel, 'numeric');
            app.EndPointEditFieldLabel = uilabel(app.BadDataSegmentPanel);
            app.EndPointEditFieldLabel.Text = 'End Point';
            app.EndPointEditField = uieditfield(app.BadDataSegmentPanel, 'numeric');
            app.ChooseButton = uibutton(app.BadDataSegmentPanel, 'push');
            app.ChooseButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseButtonPushed, true);
            app.ChooseButton.Text = 'Choose';
            app.ChooseButton_2 = uibutton(app.BadDataSegmentPanel, 'push');
            app.ChooseButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChooseButton_2Pushed, true);
            app.ChooseButton_2.Text = 'Choose';
            app.AddBadSegmentButton = uibutton(app.BadDataSegmentPanel, 'push');
            app.AddBadSegmentButton.ButtonPushedFcn = createCallbackFcn(app, @AddBadSegmentButtonPushed, true);
            app.AddBadSegmentButton.Text = 'Add Bad Segment';
            app.PanelNumEditFieldLabel = uilabel(app.BadDataSegmentPanel);
            app.PanelNumEditFieldLabel.Text = 'Panel Num';
            app.PanelNumEditField = uieditfield(app.BadDataSegmentPanel, 'numeric');
            app.IISTab = uitab(app.TabGroup);
            app.IISTab.Title = 'IIS';
            app.Panel1_2 = uipanel(app.IISTab);
            app.Panel1_2.Title = 'Panel 1';
            app.IISFileEditFieldLabel = uilabel(app.Panel1_2);
            app.IISFileEditFieldLabel.Text = 'IIS File';
            app.IISFileEditField_1 = uieditfield(app.Panel1_2, 'text');
            app.DeleteinCurrentTimeWindowButton_1 = uibutton(app.Panel1_2, 'push');
            app.DeleteinCurrentTimeWindowButton_1.ButtonPushedFcn = createCallbackFcn(app, @DeleteinCurrentTimeWindowButton_1Pushed, true);
            app.DeleteinCurrentTimeWindowButton_1.Text = 'Delete in Current Time Window';
            app.DeletefromEntireChannelButton_1 = uibutton(app.Panel1_2, 'push');
            app.DeletefromEntireChannelButton_1.ButtonPushedFcn = createCallbackFcn(app, @DeletefromEntireChannelButton_1Pushed, true);
            app.DeletefromEntireChannelButton_1.Text = 'Delete from Entire Channel';
            app.LoadButton_1 = uibutton(app.Panel1_2, 'push');
            app.LoadButton_1.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_1Pushed, true);
            app.LoadButton_1.Text = 'Load';
            app.SaveButton_1 = uibutton(app.Panel1_2, 'push');
            app.SaveButton_1.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_1Pushed, true);
            app.SaveButton_1.Text = 'Save';
            app.ShowAutomaticDetectionButton_1 = uibutton(app.Panel1_2, 'push');
            app.ShowAutomaticDetectionButton_1.ButtonPushedFcn = createCallbackFcn(app, @ShowAutomaticDetectionButton_1Pushed, true);
            app.ShowAutomaticDetectionButton_1.Text = 'Show Automatic Detection';
            app.HideAutomaticDetectionButton_1 = uibutton(app.Panel1_2, 'push');
            app.HideAutomaticDetectionButton_1.ButtonPushedFcn = createCallbackFcn(app, @HideAutomaticDetectionButton_1Pushed, true);
            app.HideAutomaticDetectionButton_1.Text = 'Hide Automatic Detection';
            app.BrowseButton_11 = uibutton(app.Panel1_2, 'push');
            app.BrowseButton_11.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_11Pushed2, true);
            app.BrowseButton_11.Text = 'Browse';
            app.AutosaveCheckBox_1 = uicheckbox(app.Panel1_2);
            app.AutosaveCheckBox_1.ValueChangedFcn = createCallbackFcn(app, @AutosaveCheckBox_1ValueChanged, true);
            app.AutosaveCheckBox_1.Text = 'Auto-save';
            app.AutosaveCheckBox_1.Value = true;
            app.AcceptAutomaticButton_1 = uibutton(app.Panel1_2, 'push');
            app.AcceptAutomaticButton_1.ButtonPushedFcn = createCallbackFcn(app, @AcceptAutomaticButton_1Pushed, true);
            app.AcceptAutomaticButton_1.Text = 'Accept Automatic';
            app.Panel2_2 = uipanel(app.IISTab);
            app.Panel2_2.Title = 'Panel 2';
            app.IISFileEditField_2Label = uilabel(app.Panel2_2);
            app.IISFileEditField_2Label.Text = 'IIS File';
            app.IISFileEditField_2 = uieditfield(app.Panel2_2, 'text');
            app.DeleteCurrentTimeWindowButton_2 = uibutton(app.Panel2_2, 'push');
            app.DeleteCurrentTimeWindowButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentTimeWindowButton_2Pushed, true);
            app.DeleteCurrentTimeWindowButton_2.Text = 'Delete Current Time Window';
            app.DeletefromEntireChannelButton_2 = uibutton(app.Panel2_2, 'push');
            app.DeletefromEntireChannelButton_2.ButtonPushedFcn = createCallbackFcn(app, @DeletefromEntireChannelButton_2Pushed, true);
            app.DeletefromEntireChannelButton_2.Text = 'Delete from Entire Channel';
            app.LoadButton_2 = uibutton(app.Panel2_2, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Text = 'Load';
            app.SaveButton_2 = uibutton(app.Panel2_2, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2Pushed, true);
            app.SaveButton_2.Text = 'Save';
            app.BrowseButton_12 = uibutton(app.Panel2_2, 'push');
            app.BrowseButton_12.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_12Pushed2, true);
            app.BrowseButton_12.Text = 'Browse';
            app.ShowAutomaticDetectionButton_2 = uibutton(app.Panel2_2, 'push');
            app.ShowAutomaticDetectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @ShowAutomaticDetectionButton_2Pushed, true);
            app.ShowAutomaticDetectionButton_2.Text = 'Show Automatic Detection';
            app.HideAutomaticDetectionButton_2 = uibutton(app.Panel2_2, 'push');
            app.HideAutomaticDetectionButton_2.ButtonPushedFcn = createCallbackFcn(app, @HideAutomaticDetectionButton_2Pushed, true);
            app.HideAutomaticDetectionButton_2.Text = 'Hide Automatic Detection';
            app.AutosaveCheckBox_2 = uicheckbox(app.Panel2_2);
            app.AutosaveCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @AutosaveCheckBox_2ValueChanged, true);
            app.AutosaveCheckBox_2.Text = 'Auto-save';
            app.AutosaveCheckBox_2.Value = true;
            app.AcceptAutomaticButton_2 = uibutton(app.Panel2_2, 'push');
            app.AcceptAutomaticButton_2.ButtonPushedFcn = createCallbackFcn(app, @AcceptAutomaticButton_2Pushed, true);
            app.AcceptAutomaticButton_2.Text = 'Accept Automatic';
            app.Panel3_2 = uipanel(app.IISTab);
            app.Panel3_2.Title = 'Panel 3';
            app.DeleteCurrentTimeWindowButton_3 = uibutton(app.Panel3_2, 'push');
            app.DeleteCurrentTimeWindowButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentTimeWindowButton_3Pushed, true);
            app.DeleteCurrentTimeWindowButton_3.Text = 'Delete Current Time Window';
            app.DeletefromEntireChannelButton_3 = uibutton(app.Panel3_2, 'push');
            app.DeletefromEntireChannelButton_3.ButtonPushedFcn = createCallbackFcn(app, @DeletefromEntireChannelButton_3Pushed, true);
            app.DeletefromEntireChannelButton_3.Text = 'Delete from Entire Channel';
            app.IISFileEditField_3Label = uilabel(app.Panel3_2);
            app.IISFileEditField_3Label.Text = 'IIS File';
            app.IISFileEditField_3 = uieditfield(app.Panel3_2, 'text');
            app.LoadButton_3 = uibutton(app.Panel3_2, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Text = 'Load';
            app.SaveButton_3 = uibutton(app.Panel3_2, 'push');
            app.SaveButton_3.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_3Pushed, true);
            app.SaveButton_3.Text = 'Save';
            app.BrowseButton_13 = uibutton(app.Panel3_2, 'push');
            app.BrowseButton_13.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_13Pushed2, true);
            app.BrowseButton_13.Text = 'Browse';
            app.ShowAutomaticDetectionButton_3 = uibutton(app.Panel3_2, 'push');
            app.ShowAutomaticDetectionButton_3.ButtonPushedFcn = createCallbackFcn(app, @ShowAutomaticDetectionButton_3Pushed, true);
            app.ShowAutomaticDetectionButton_3.Text = 'Show Automatic Detection';
            app.HideAutomaticDetectionButton_3 = uibutton(app.Panel3_2, 'push');
            app.HideAutomaticDetectionButton_3.ButtonPushedFcn = createCallbackFcn(app, @HideAutomaticDetectionButton_3Pushed, true);
            app.HideAutomaticDetectionButton_3.Text = 'Hide Automatic Detection';
            app.AutosaveCheckBox_3 = uicheckbox(app.Panel3_2);
            app.AutosaveCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @AutosaveCheckBox_3ValueChanged, true);
            app.AutosaveCheckBox_3.Text = 'Auto-save';
            app.AutosaveCheckBox_3.Value = true;
            app.AcceptAutomaticButton_3 = uibutton(app.Panel3_2, 'push');
            app.AcceptAutomaticButton_3.ButtonPushedFcn = createCallbackFcn(app, @AcceptAutomaticButton_3Pushed, true);
            app.AcceptAutomaticButton_3.Text = 'Accept Automatic';
            app.Panel4_2 = uipanel(app.IISTab);
            app.Panel4_2.Title = 'Panel 4';
            app.DeleteCurrentTimeWindowButton_4 = uibutton(app.Panel4_2, 'push');
            app.DeleteCurrentTimeWindowButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentTimeWindowButton_4Pushed, true);
            app.DeleteCurrentTimeWindowButton_4.Text = 'Delete Current Time Window';
            app.DeletefromEntireChannelButton_4 = uibutton(app.Panel4_2, 'push');
            app.DeletefromEntireChannelButton_4.ButtonPushedFcn = createCallbackFcn(app, @DeletefromEntireChannelButton_4Pushed, true);
            app.DeletefromEntireChannelButton_4.Text = 'Delete from Entire Channel';
            app.IISFileEditField_4Label = uilabel(app.Panel4_2);
            app.IISFileEditField_4Label.Text = 'IIS File';
            app.IISFileEditField_4 = uieditfield(app.Panel4_2, 'text');
            app.LoadButton_4 = uibutton(app.Panel4_2, 'push');
            app.LoadButton_4.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_4Pushed, true);
            app.LoadButton_4.Text = 'Load';
            app.SaveButton_4 = uibutton(app.Panel4_2, 'push');
            app.SaveButton_4.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_4Pushed, true);
            app.SaveButton_4.Text = 'Save';
            app.BrowseButton_14 = uibutton(app.Panel4_2, 'push');
            app.BrowseButton_14.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_14Pushed2, true);
            app.BrowseButton_14.Text = 'Browse';
            app.ShowAutomaticDetectionButton_4 = uibutton(app.Panel4_2, 'push');
            app.ShowAutomaticDetectionButton_4.ButtonPushedFcn = createCallbackFcn(app, @ShowAutomaticDetectionButton_4Pushed, true);
            app.ShowAutomaticDetectionButton_4.Text = 'Show Automatic Detection';
            app.HideAutomaticDetectionButton_4 = uibutton(app.Panel4_2, 'push');
            app.HideAutomaticDetectionButton_4.ButtonPushedFcn = createCallbackFcn(app, @HideAutomaticDetectionButton_4Pushed, true);
            app.HideAutomaticDetectionButton_4.Text = 'Hide Automatic Detection';
            app.AutosaveCheckBox_4 = uicheckbox(app.Panel4_2);
            app.AutosaveCheckBox_4.ValueChangedFcn = createCallbackFcn(app, @AutosaveCheckBox_4ValueChanged, true);
            app.AutosaveCheckBox_4.Text = 'Auto-save';
            app.AutosaveCheckBox_4.Value = true;
            app.AcceptAutomaticButton_4 = uibutton(app.Panel4_2, 'push');
            app.AcceptAutomaticButton_4.ButtonPushedFcn = createCallbackFcn(app, @AcceptAutomaticButton_4Pushed, true);
            app.AcceptAutomaticButton_4.Text = 'Accept Automatic';
            app.Panel5_2 = uipanel(app.IISTab);
            app.Panel5_2.Title = 'Panel 5';
            app.DeleteCurrentTimeWindowButton_5 = uibutton(app.Panel5_2, 'push');
            app.DeleteCurrentTimeWindowButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeleteCurrentTimeWindowButton_5Pushed, true);
            app.DeleteCurrentTimeWindowButton_5.Text = 'Delete Current Time Window';
            app.DeletefromEntireChannelButton_5 = uibutton(app.Panel5_2, 'push');
            app.DeletefromEntireChannelButton_5.ButtonPushedFcn = createCallbackFcn(app, @DeletefromEntireChannelButton_5Pushed, true);
            app.DeletefromEntireChannelButton_5.Text = 'Delete from Entire Channel';
            app.IISFileEditField_5Label = uilabel(app.Panel5_2);
            app.IISFileEditField_5Label.Text = 'IIS File';
            app.IISFileEditField_5 = uieditfield(app.Panel5_2, 'text');
            app.LoadButton_5 = uibutton(app.Panel5_2, 'push');
            app.LoadButton_5.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_5Pushed, true);
            app.LoadButton_5.Text = 'Load';
            app.SaveButton_5 = uibutton(app.Panel5_2, 'push');
            app.SaveButton_5.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_5Pushed, true);
            app.SaveButton_5.Text = 'Save';
            app.BrowseButton_15 = uibutton(app.Panel5_2, 'push');
            app.BrowseButton_15.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_15Pushed2, true);
            app.BrowseButton_15.Text = 'Browse';
            app.ShowAutomaticDetectionButton_5 = uibutton(app.Panel5_2, 'push');
            app.ShowAutomaticDetectionButton_5.ButtonPushedFcn = createCallbackFcn(app, @ShowAutomaticDetectionButton_5Pushed, true);
            app.ShowAutomaticDetectionButton_5.Text = 'Show Automatic Detection';
            app.HideAutomaticDetectionButton_5 = uibutton(app.Panel5_2, 'push');
            app.HideAutomaticDetectionButton_5.ButtonPushedFcn = createCallbackFcn(app, @HideAutomaticDetectionButton_5Pushed, true);
            app.HideAutomaticDetectionButton_5.Text = 'Hide Automatic Detection';
            app.AutosaveCheckBox_5 = uicheckbox(app.Panel5_2);
            app.AutosaveCheckBox_5.ValueChangedFcn = createCallbackFcn(app, @AutosaveCheckBox_5ValueChanged, true);
            app.AutosaveCheckBox_5.Text = 'Auto-save';
            app.AutosaveCheckBox_5.Value = true;
            app.AcceptAutomaticButton_5 = uibutton(app.Panel5_2, 'push');
            app.AcceptAutomaticButton_5.ButtonPushedFcn = createCallbackFcn(app, @AcceptAutomaticButton_5Pushed, true);
            app.AcceptAutomaticButton_5.Text = 'Accept Automatic';
            app.LoadAllButton = uibutton(app.IISTab, 'push');
            app.LoadAllButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAllButtonPushed, true);
            app.LoadAllButton.Text = 'Load All';
            app.SaveAllButton = uibutton(app.IISTab, 'push');
            app.SaveAllButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllButtonPushed, true);
            app.SaveAllButton.Text = 'Save All';
            app.DeleteAllinCurrentWindowButton = uibutton(app.IISTab, 'push');
            app.DeleteAllinCurrentWindowButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllinCurrentWindowButtonPushed, true);
            app.DeleteAllinCurrentWindowButton.Text = 'Delete All in Current Window';
            app.DeleteAllButton = uibutton(app.IISTab, 'push');
            app.DeleteAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteAllButtonPushed, true);
            app.DeleteAllButton.Text = 'Delete All';
            app.ShowAutomaticDetectionforAllButton = uibutton(app.IISTab, 'push');
            app.ShowAutomaticDetectionforAllButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAutomaticDetectionforAllButtonPushed, true);
            app.ShowAutomaticDetectionforAllButton.Text = 'Show Automatic Detection for All';
            app.HideAutomaticDetectionforAllButton = uibutton(app.IISTab, 'push');
            app.HideAutomaticDetectionforAllButton.ButtonPushedFcn = createCallbackFcn(app, @HideAutomaticDetectionforAllButtonPushed, true);
            app.HideAutomaticDetectionforAllButton.Text = 'Hide Automatic Detection for All';
            app.AcceptAutomaticDetectionforallButton = uibutton(app.IISTab, 'push');
            app.AcceptAutomaticDetectionforallButton.ButtonPushedFcn = createCallbackFcn(app, @AcceptAutomaticDetectionforallButtonPushed, true);
            app.AcceptAutomaticDetectionforallButton.Text = 'Accept Automatic Detection for all';
        end
    end

    methods (Access = public)

        % Construct app
        function app = IISView

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end